classdef transmisor_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        ComenzarButton              matlab.ui.control.StateButton
        CodificacionCheckBox        matlab.ui.control.CheckBox
        ModulacionButtonGroup       matlab.ui.container.ButtonGroup
        PSKButton                   matlab.ui.control.RadioButton
        QPSKButton                  matlab.ui.control.RadioButton
        BPSKButton                  matlab.ui.control.RadioButton
        SNR_dBSlider                matlab.ui.control.Slider
        SNR_dBSliderLabel           matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        BERHamming74EditField       matlab.ui.control.NumericEditField
        BERHamming74EditFieldLabel  matlab.ui.control.Label
        BERViterbiEditField         matlab.ui.control.NumericEditField
        BERViterbiEditFieldLabel    matlab.ui.control.Label
        BER_Sin_Codificacion        matlab.ui.control.NumericEditField
        BEREditFieldLabel           matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes1                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ComenzarButton
        function ComenzarButtonValueChanged(app, event)
            if app.ComenzarButton.Value == false, return; end
            
            app.BERViterbiEditFieldLabel.Visible = 'off';
            app.BERViterbiEditField.Visible = 'off';
            app.BERHamming74EditField.Visible = 'off';
            app.BERHamming74EditFieldLabel.Visible = 'off';
            app.BER_Sin_Codificacion.Visible = 'off';
            app.BEREditFieldLabel.Visible = 'off';


            % Seleccionar el tipo de moduacion que se quiere
            tipo_modulacion = app.ModulacionButtonGroup.SelectedObject.Text;
            % Seleccionar el nivel de ruido que se quiere.
            SNR_dB_actual = app.SNR_dBSlider.Value;
            % Ver si se quiere codificaco o no.
            estado_codificacion = app.CodificacionCheckBox.Value;


            % Parametros de simulacion
            n_bits = 1e3;
            roll_off = 0.3;
            spp = 8;

            datos_originales = randi([0 1],1,n_bits);
            if estado_codificacion
                % Codificacion Viterbi
                bits_transmitir_viterbi = codificar_convolucion(datos_originales,[171,133]);
                R_v = 1/2;
                
                %Codificacion Hamming
                bits_transmitir_hamming = codificar_hamming(datos_originales);
                R_h = 4/7;
            else
                bits_transmitir = datos_originales;  
                R = 1;
            end

            switch tipo_modulacion
                case 'QPSK'
                    if estado_codificacion
                        simbolos_viterbi = modulador_qpsk(bits_transmitir_viterbi);
                        simbolos_hamming = modulador_qpsk(bits_transmitir_hamming);
                    else
                        simbolos = modulador_qpsk(bits_transmitir);
                    end
                    k = 2;
                case 'BPSK'
                    if estado_codificacion
                        simbolos_viterbi = modulador_bpsk(bits_transmitir_viterbi);
                        simbolos_hamming = modulador_bpsk(bits_transmitir_hamming);
                    else
                        simbolos = modulador_bpsk(bits_transmitir);
                    end
                    k = 1;
                case '8-PSK'
                    if estado_codificacion
                        simbolos_viterbi = modulador_8psk(bits_transmitir_viterbi);
                        simbolos_hamming = modulador_8psk(bits_transmitir_hamming);
                    else
                        simbolos = modulador_8psk(bits_transmitir);
                    end
                    k = 3;
            end

            if estado_codificacion

                % canal usando decodificacion viterbi
                snr_canal_viterbi = SNR_dB_actual + 10*log10(k*R_v);
                senial_ruidosa_viterbi = canal_awgn(simbolos_viterbi,snr_canal_viterbi);
    
                % canal usando codificacion hamming
                snr_canal_hamming = SNR_dB_actual + 10*log10(k*R_h);
                senial_ruidosa_hamming = canal_awgn(simbolos_hamming,snr_canal_hamming);

                switch tipo_modulacion
                    case 'QPSK' 
                        bits_demodulados_viterbi = demodulador_qpsk(senial_ruidosa_viterbi);
                        bits_demodulados_hamming = demodulador_qpsk(senial_ruidosa_hamming);
                    case 'BPSK'
                        bits_demodulados_viterbi = demodulador_bpsk(senial_ruidosa_viterbi);
                        bits_demodulados_hamming = demodulador_bpsk(senial_ruidosa_hamming);
                    case '8-PSK'
                        bits_demodulados_viterbi = demodulador_8psk(senial_ruidosa_viterbi);
                        bits_demodulados_hamming = demodulador_8psk(senial_ruidosa_hamming);
                end
                
                
                bits_obtenidos_viterbi = decodificar_viterbi(bits_demodulados_viterbi);
                bits_obtenidos_hamming = decodificar_hamming(bits_demodulados_hamming);
 
                errores_viterbi = sum(xor(datos_originales,bits_obtenidos_viterbi));
                errores_hamming = sum(xor(datos_originales,bits_obtenidos_hamming));
                
                BER_Viterbi = errores_viterbi/length(datos_originales);
                BER_Hamming = errores_hamming/length(datos_originales);

                app.BERViterbiEditField.Value = BER_Viterbi;
                app.BERHamming74EditField.Value = BER_Hamming;

                app.BERViterbiEditField.Visible ='on';
                app.BERHamming74EditField.Visible = 'on';

                app.BERViterbiEditFieldLabel.Visible = 'on';
                app.BERHamming74EditFieldLabel.Visible = 'on';
    
                %Grafica de constelacion 
                app.UIAxes1.Visible = 'on';
                cla(app.UIAxes1);
                plot(app.UIAxes1, real(senial_ruidosa_viterbi), imag(senial_ruidosa_viterbi), '.', 'MarkerSize', 10,'Color','b');
                hold(app.UIAxes1,'on');
                plot(app.UIAxes1, real(senial_ruidosa_hamming), imag(senial_ruidosa_hamming), '.', 'MarkerSize', 10,'Color','r');
                hold(app.UIAxes1,'on');
                xline(app.UIAxes1, 0, 'Color', [0.2 0.2 0.2], 'LineWidth', 1.5);
                yline(app.UIAxes1, 0, 'Color', [0.2 0.2 0.2], 'LineWidth', 1.5);
                grid(app.UIAxes1, 'on');
                xlabel(app.UIAxes1, 'En Fase (I)');
                ylabel(app.UIAxes1, 'Cuadratura (Q)');
                legend(app.UIAxes1,['Convolucion'],['Hamming']);
                title(app.UIAxes1, ['Constelación Recibida - ', tipo_modulacion]);
                axis(app.UIAxes1, 'equal');
                app.UIAxes1.XLim = [-3 3];
                app.UIAxes1.YLim = [-3 3];
                hold(app.UIAxes1, 'off');

                SNR_dB = 0:1:15;
                BER_Practico_Viterbi = zeros(1,length(SNR_dB));
                BER_Practico_Hamming = zeros(1,length(SNR_dB));
                EbNo = 10.^(SNR_dB/10);

                M=2^k;
                SER_teorico = 2*qfunc(sqrt(2*log2(M)*EbNo*(sin(pi/M)^2))); 
                BER_Teorico = SER_teorico/k;
    
                for snr = SNR_dB
                    % Viterbi
                    snr_canal_viterbi = snr + 10*log10(k*R_v);
                    senial_ruidosa_viterbi = canal_awgn(simbolos_viterbi,snr_canal_viterbi);
                    
                    % Hamming
                    snr_canal_hamming = snr + 10*log10(k*R_h);
                    senial_ruidosa_hamming = canal_awgn(simbolos_hamming,snr_canal_hamming);
                    switch tipo_modulacion
                        case 'QPSK' 
                            bits_demodulados_viterbi = demodulador_qpsk(senial_ruidosa_viterbi);
                            bits_demodulados_hamming = demodulador_qpsk(senial_ruidosa_hamming);
                        case 'BPSK'
                            bits_demodulados_viterbi = demodulador_bpsk(senial_ruidosa_viterbi);
                            bits_demodulados_hamming = demodulador_bpsk(senial_ruidosa_hamming);
                        case '8-PSK'
                            bits_demodulados_viterbi = demodulador_8psk(senial_ruidosa_viterbi);
                            bits_demodulados_hamming = demodulador_8psk(senial_ruidosa_hamming);
                    end
                    
                    
                    bits_obtenidos_viterbi = decodificar_viterbi(bits_demodulados_viterbi);
                    bits_obtenidos_hamming = decodificar_hamming(bits_demodulados_hamming);
     
                    errores_viterbi = sum(xor(datos_originales,bits_obtenidos_viterbi));
                    errores_hamming = sum(xor(datos_originales,bits_obtenidos_hamming));
                    
                    BER_Practico_Viterbi(snr+1) = errores_viterbi/length(datos_originales);
                    BER_Practico_Hamming(snr+1) = errores_hamming/length(datos_originales);
                end
                
                % Graficas de rendimiento
                app.UIAxes2.Visible = 'on';
                cla(app.UIAxes2);
                semilogy(app.UIAxes2, SNR_dB, BER_Practico_Viterbi, '-bo', 'LineWidth', 1.5, 'MarkerFaceColor', 'b');
                hold(app.UIAxes2,'on');
                semilogy(app.UIAxes2, SNR_dB, BER_Practico_Hamming, '-go', 'LineWidth', 1.5, 'MarkerFaceColor', 'g');
                semilogy(app.UIAxes2, SNR_dB, BER_Teorico,'r','LineWidth', 1.5);
                grid(app.UIAxes2, 'on');
                legend(app.UIAxes2,['Viterbi ' tipo_modulacion],['Hamming ' tipo_modulacion],['Teorico ' tipo_modulacion]);
                xlabel(app.UIAxes2, 'SNR (dB)');
                ylabel(app.UIAxes2, 'BER');
                title(app.UIAxes2, 'BER vs SNR (dB)');

            else
                snr_canal = SNR_dB_actual + 10*log10(k*R);
                senial_ruidosa = canal_awgn(simbolos,snr_canal);
                
                switch tipo_modulacion
                    case 'QPSK' 
                        bits_demodulados = demodulador_qpsk(senial_ruidosa);
                    case 'BPSK'
                        bits_demodulados = demodulador_bpsk(senial_ruidosa);
                    case '8-PSK'
                        bits_demodulados = demodulador_8psk(senial_ruidosa);
                end
                bits_demodulados = bits_demodulados(1:length(datos_originales));
                errores = sum(xor(datos_originales,bits_demodulados));
                    
                BER = errores/length(datos_originales);

                app.BER_Sin_Codificacion.Value = BER;
                app.BER_Sin_Codificacion.Visible = 'on';
                app.BEREditFieldLabel.Visible = 'on';

    
                %Grafica de constelacion 
                app.UIAxes1.Visible = 'on';
                cla(app.UIAxes1);
                plot(app.UIAxes1, real(senial_ruidosa), imag(senial_ruidosa), '.', 'MarkerSize', 10,'Color','b');
                hold(app.UIAxes1,'on');
                xline(app.UIAxes1, 0, 'Color', [0.2 0.2 0.2], 'LineWidth', 1.5);
                yline(app.UIAxes1, 0, 'Color', [0.2 0.2 0.2], 'LineWidth', 1.5);
                grid(app.UIAxes1, 'on');
                xlabel(app.UIAxes1, 'En Fase (I)');
                ylabel(app.UIAxes1, 'Cuadratura (Q)');
                legend(app.UIAxes1,'Recibidos');
                title(app.UIAxes1, ['Constelación Recibida - ', tipo_modulacion]);
                axis(app.UIAxes1, 'equal');
                app.UIAxes1.XLim = [-3 3];
                app.UIAxes1.YLim = [-3 3];
                hold(app.UIAxes1, 'off');

                SNR_dB = 0:1:15;
                BER_Practico = zeros(1,length(SNR_dB));
                EbNo = 10.^(SNR_dB/10);

                M=2^k;
                SER_teorico = 2*qfunc(sqrt(2*log2(M)*EbNo*(sin(pi/M)^2))); 
                BER_Teorico = SER_teorico/k;
    
                for snr = SNR_dB
                    snr_canal = snr + 10*log10(k*R);
                    senial_ruidosa = canal_awgn(simbolos,snr_canal);
                    
                    switch tipo_modulacion
                        case 'QPSK' 
                            bits_demodulados = demodulador_qpsk(senial_ruidosa);
                        case 'BPSK'
                            bits_demodulados = demodulador_bpsk(senial_ruidosa);
                        case '8-PSK'
                            bits_demodulados = demodulador_8psk(senial_ruidosa);
                    end
                    bits_demodulados = bits_demodulados(1:length(datos_originales));
                    errores = sum(xor(datos_originales,bits_demodulados));
                    
                    BER_Practico(snr+1) = errores/length(datos_originales);
       
                end
    
                % Graficas de rendimiento
                app.UIAxes2.Visible = 'on';
                cla(app.UIAxes2);
                semilogy(app.UIAxes2, SNR_dB, BER_Practico, '-o', 'LineWidth', 1.5, 'MarkerFaceColor', 'b');
                hold(app.UIAxes2,'on');
                semilogy(app.UIAxes2, SNR_dB, BER_Teorico, 'LineWidth', 1.5, 'MarkerFaceColor', 'r');
                grid(app.UIAxes2, 'on');
                legend(app.UIAxes2,['Practico ' tipo_modulacion],['Teorico ' tipo_modulacion]);
                xlabel(app.UIAxes2, 'SNR (dB)');
                ylabel(app.UIAxes2, 'BER');
                title(app.UIAxes2, 'BER vs SNR (dB)');
            end
            app.ComenzarButton.Value = false;


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {762, 762};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {316, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SNR_dBSliderLabel = uilabel(app.LeftPanel);
            app.SNR_dBSliderLabel.Text = 'SNR_dB';
            app.SNR_dBSlider = uislider(app.LeftPanel);
            app.SNR_dBSlider.Limits = [0 20];
            app.SNR_dBSlider.MajorTicks = [0 5 10 15 20];
            app.ModulacionButtonGroup = uibuttongroup(app.LeftPanel);
            app.ModulacionButtonGroup.Title = 'Modulacion';
            app.BPSKButton = uiradiobutton(app.ModulacionButtonGroup);
            app.BPSKButton.Text = 'BPSK';
            app.BPSKButton.Value = true;
            app.QPSKButton = uiradiobutton(app.ModulacionButtonGroup);
            app.QPSKButton.Text = 'QPSK';
            app.PSKButton = uiradiobutton(app.ModulacionButtonGroup);
            app.PSKButton.Text = '8-PSK';
            app.CodificacionCheckBox = uicheckbox(app.LeftPanel);
            app.CodificacionCheckBox.Text = 'Codificacion';
            app.ComenzarButton = uibutton(app.LeftPanel, 'state');
            app.ComenzarButton.ValueChangedFcn = createCallbackFcn(app, @ComenzarButtonValueChanged, true);
            app.ComenzarButton.Text = 'Comenzar';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes1 = uiaxes(app.RightPanel);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes1.Visible = 'off';
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.BEREditFieldLabel = uilabel(app.RightPanel);
            app.BEREditFieldLabel.Visible = 'off';
            app.BEREditFieldLabel.Text = 'BER';
            app.BER_Sin_Codificacion = uieditfield(app.RightPanel, 'numeric');
            app.BER_Sin_Codificacion.Editable = 'off';
            app.BER_Sin_Codificacion.Visible = 'off';
            app.BERViterbiEditFieldLabel = uilabel(app.RightPanel);
            app.BERViterbiEditFieldLabel.Visible = 'off';
            app.BERViterbiEditFieldLabel.Text = 'BER Viterbi';
            app.BERViterbiEditField = uieditfield(app.RightPanel, 'numeric');
            app.BERViterbiEditField.Editable = 'off';
            app.BERViterbiEditField.Visible = 'off';
            app.BERHamming74EditFieldLabel = uilabel(app.RightPanel);
            app.BERHamming74EditFieldLabel.Visible = 'off';
            app.BERHamming74EditFieldLabel.Text = 'BER Hamming(7,4)';
            app.BERHamming74EditField = uieditfield(app.RightPanel, 'numeric');
            app.BERHamming74EditField.Editable = 'off';
            app.BERHamming74EditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transmisor_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end